classdef CCPStackingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        StationMapPanel             matlab.ui.container.Panel
        PlotparametersPanel         matlab.ui.container.Panel
        RunButton_2                 matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        Y2EditField                 matlab.ui.control.NumericEditField
        Y2EditFieldLabel            matlab.ui.control.Label
        X2EditField                 matlab.ui.control.NumericEditField
        X2EditFieldLabel            matlab.ui.control.Label
        Y1EditField                 matlab.ui.control.NumericEditField
        Y1EditFieldLabel            matlab.ui.control.Label
        X1EditField                 matlab.ui.control.NumericEditField
        X1EditFieldLabel            matlab.ui.control.Label
        CCPparametersPanel          matlab.ui.container.Panel
        VelocityModelDropDown       matlab.ui.control.DropDown
        VelocityModelDropDownLabel  matlab.ui.control.Label
        RunButton                   matlab.ui.control.Button
        QuitButton                  matlab.ui.control.Button
        SmoothingkmEditField        matlab.ui.control.NumericEditField
        SmoothingkmEditFieldLabel   matlab.ui.control.Label
        dzkmEditField               matlab.ui.control.NumericEditField
        dzkmEditFieldLabel          matlab.ui.control.Label
        zmaxkmEditField             matlab.ui.control.NumericEditField
        zmaxkmEditFieldLabel        matlab.ui.control.Label
        dykmEditField               matlab.ui.control.NumericEditField
        dykmEditFieldLabel          matlab.ui.control.Label
        dxkmEditField               matlab.ui.control.NumericEditField
        dxkmEditFieldLabel          matlab.ui.control.Label
        StatusEditField             matlab.ui.control.EditField
        StatusEditFieldLabel        matlab.ui.control.Label
        CCPStackingPanelLabel       matlab.ui.control.Label
        CCPImageMap                 matlab.ui.control.UIAxes
    end

    properties (Access = public)
        mainApp
        DataStruct          % 用于存放地震数据
        ccpResult       % 存放叠加后的结果
        gridStruct

        plotFlag=false

    end
    
    methods (Access = private)
        
        function plotStationsInApp(app, gridStruct,plotFlag)
            delete(allchild(app.StationMapPanel));

            %------------------- 1. 获取 "所有台站" -------------------%
            allSt = getStations(app.DataStruct);  % struct array
            allLats  = [allSt.stla];
            allLons  = [allSt.stlo];
            allNames = {allSt.sta};
            originLon = min(allLons);
            originLat = min(allLats);
            
            % 将经纬度转换为笛卡尔坐标（需确保 latlon2xy 函数正确）
            [stationX, stationY] = latlon2xy(allLons, allLats, originLon, originLat);
            
            % rx = gridStruct.rxInOriginalCoord;
            % ry = gridStruct.ryInOriginalCoord;
        
            %------------------- 2. 清空 Panel 并创建坐标轴 -------------------%
            gax = axes('Parent', app.StationMapPanel, ...
                'Units', 'normalized', ...
                'Position', [0 0 1 1]);
            hold(gax, 'on'); % 统一使用坐标轴句柄
        
            %------------------- 3. 绘制台站、网格点和投影点 -------------------%
            % 绘制台站（红色三角）
            scatter(gax, stationX, stationY, 40, 'r^', 'filled', ...
                'DisplayName', '台站位置');
            
            % 绘制网格点（灰色点）
            XX = gridStruct.X;
            YY = gridStruct.Y;
            scatter(gax, XX, YY, 80, [0.5 0.5 0.5], '.', ...
                'filled', 'MarkerEdgeColor', 'k', ...
                'DisplayName', '网格点');
            
            %------------------- 4. 添加标签和图例 -------------------%
            xlabel(gax, '东向距离 (km)', 'FontSize', 10);
            ylabel(gax, '北向距离 (km)', 'FontSize', 10);
            title(gax, '台站与网格分布图', 'FontSize', 12);
            % legend(gax, 'show', 'Location', 'bestoutside');
            
            % 调整坐标轴比例，确保等比例显示
            axis(gax, 'equal');
            xlim(gax,[min(XX(:)) max(XX(:))])
            xlim(gax,[min(YY(:)) max(YY(:))])

            if plotFlag == 1
                coord1 = [app.X1EditField.Value,app.Y1EditField.Value];
                coord2 = [app.X2EditField.Value,app.Y2EditField.Value];
    
                plot(gax,[coord1(1),coord2(1)],[coord1(2),coord2(2)],'b-o','LineWidth',2)
                % plot(gax,coord1, 'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'r');  % 红色填充圆点
                % plot(gax,coord1, 'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'r');

                text(gax,coord1(1),coord1(2),'A','FontSize',20,'HorizontalAlignment','right')
                text(gax,coord2(1),coord2(2),'B','FontSize',20,'HorizontalAlignment','left')
            end

            grid(gax, 'on');
            hold(gax, 'off');
        end

    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            answer = questdlg('是否退出？', '', 'YES', 'NO', 'NO');
        
            % 根据用户选择执行操作
            switch answer
                case 'YES'
                    delete(app.UIFigure); % 删除主窗口以退出
                case 'NO'
                    return; % 取消关闭操作
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if isempty(app.DataStruct)
                uialert(app.UIFigure, '尚未读取数据！', '错误','Icon','error');
                return;
            end

            msg = uiprogressdlg(app.UIFigure, ...
                    'Title','CCP叠加', ...
                    'Message','提取速度模型中，请稍候...', ...
                    'Cancelable','off', ...
                    'Value',0, ...
                    'Indeterminate','off');

            eventList = getEvents(app.DataStruct);
            eventid = {eventList.evid};

            % create grid
            app.gridStruct = createGrid(app.DataStruct, app.dxkmEditField.Value, app.dykmEditField.Value, app.dzkmEditField.Value, app.zmaxkmEditField.Value);

            plotStationsInApp(app,app.gridStruct,app.plotFlag);

            % velocity model
            ModelType = app.VelocityModelDropDown.Value;

            if ModelType == 'Default'
                ModelType = '3D';
            end

            npts = 5;
            velocityModel = getVelocityModel(ModelType,app.gridStruct,npts);

            dimg = [];    % 存储最CCP结果
            count = [];
            nMigratedEvents = 1;   % 用于计数成功完成成像的事件数
            
            % 遍历所有符合筛选条件的事件
            for iEvent = 1:length(eventid)
                evid = eventid{iEvent}; 
                % 提取当前事件对应的地震记录子集（Common Event Gather）
                gather = getCommonEventGather(app.DataStruct, evid);
                % 如果该事件的有效台站数小于 50，则跳过，以保证成像质量
                if length(gather) < 155
                    continue
                end

                msg.Value = (iEvent-1)/length(eventid);
                msg.Message = sprintf('正在处理：第 (%d/%d) 个地震', iEvent, length(eventid));
                drawnow;  % 强制界面刷新
            
                % 调用 CCPCommonEventGather 进行共转换点叠加成像
                Result = CCPCommonEventGather(gather,velocityModel, app.gridStruct);
                dimg(:,:,:,nMigratedEvents) = Result.img;
                count(:,:,:,nMigratedEvents) = Result.count;
                
                nMigratedEvents = nMigratedEvents + 1;
            end

            app.ccpResult.img = dimg;
            app.ccpResult.X = Result.X;
            app.ccpResult.Y = Result.Y;
            app.ccpResult.Z = Result.Z;

            msg.Value = 1;
            msg.Message = '所有数据处理完成！';
            drawnow;

            close(msg);
            uialert(app.UIFigure, 'CCP计算完成！', '提示');
            app.StatusEditField.Value = 'CCP处理完成';

        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
            
            smoothLength = fix(app.SmoothingkmEditField.Value/app.dxkmEditField.Value) + 1;

            if rem(smoothLength,2)==0
                smoothLength = smoothLength +1;
            end

            app.plotFlag = true;
            plotStationsInApp(app,app.gridStruct,app.plotFlag);

            msg = uiprogressdlg(app.UIFigure, ...
                    'Title','CCP结果', ...
                    'Message','结果展示，请稍候...', ...
                    'Cancelable','off', ...
                    'Value',0, ...
                    'Indeterminate','off');
            
            X = app.ccpResult.X;
            Y = app.ccpResult.Y;
            Z = app.ccpResult.Z;
            [~,~,nz] = size(X);
            dimg_smooth = [];
            
            for n = 1:size(app.ccpResult.img,4)
                dimg_smooth(:,:,:,n) = smooth3(squeeze(app.ccpResult.img(:,:,:,n)),'box',smoothLength);
            end

            V_smooth = mean(dimg_smooth,4);

            % plot
            Fi = scatteredInterpolant(X(:),Y(:),Z(:),V_smooth(:));

            x1 = app.X1EditField.Value;
            x2 = app.X2EditField.Value;
            y1 = app.Y1EditField.Value;
            y2 = app.Y2EditField.Value;

            depth = squeeze(Z(1,1,:));

            xi = linspace(x1,x2,50);
            yi = linspace(y1,y2,50);

            xdist = linspace(0,sqrt((x1-x2)^2 + (y1-y2)^2),50);

            xpro = repmat(xi,nz,1);
            ypro = repmat(yi,nz,1);
            zpro = repmat(depth,1,50);

            Vpro = Fi(xpro,ypro,zpro);

            %%
            cla(app.CCPImageMap,"reset");  % 清空并重置坐标轴
            % axes(app.CCPImageMap);
            % hold(app.CCPImageMap, 'on');    % 允许叠加绘图（如后续需要）
            imagesc(app.CCPImageMap, xdist, depth, Vpro); % 绘制图像
            
            % 设置坐标轴属性
            axis(app.CCPImageMap, 'equal'); % 等比例坐标轴
            xlabel(app.CCPImageMap, 'Distance (km)');
            ylabel(app.CCPImageMap, 'Depth (km)');
            xlim(app.CCPImageMap, [0, max(xdist)]);
            ylim(app.CCPImageMap, [0, 100]);
            text(app.CCPImageMap,0,-5,'A','FontSize',14)
            text(app.CCPImageMap,max(xdist)-5,-5,'B','FontSize',14)
                        
            % 设置颜色范围（确保使用正确的数据计算）
            cmax = rms(Vpro(:)); % 假设需基于Vpro而非V_smooth
            clim(app.CCPImageMap, [-3*cmax, 3*cmax]); % 显式指定坐标轴
            % clim(app.CCPImageMap, [-1 1]);
            
            % 可选：设置颜色图
            colormap(app.CCPImageMap, 'jet'); % 例如使用jet颜色图
            colorbar(app.CCPImageMap)
            app.StatusEditField.Value = 'CCP图像展示';

            close(msg);
            uialert(app.UIFigure, 'CCP图像绘制完成！', '提示');

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [filename,path,index] = uiputfile({...
                '*.png','PNG file'; ...
                '*.jpg','JPG file'},'请选择保存的文件名');
            if isequal(filename,0)
                disp('取消')
            else
                formattype = {'-dpng','-djpg'};
                f = figure('Visible','off');
                ax = axes(f);
                copyobj(app.CCPImageMap.Parent,ax);
                print(f,[path,filename],formattype{index});
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CCPImageMap = uiaxes(app.UIFigure);
            title(app.CCPImageMap, 'CCP image')
            xlabel(app.CCPImageMap, 'Distance (km)')
            ylabel(app.CCPImageMap, 'Depth (km)')
            zlabel(app.CCPImageMap, 'Z')
            app.CCPStackingPanelLabel = uilabel(app.UIFigure);
            app.CCPStackingPanelLabel.Text = 'CCP Stacking Panel';
            app.StatusEditFieldLabel = uilabel(app.UIFigure);
            app.StatusEditFieldLabel.Text = 'Status:';
            app.StatusEditField = uieditfield(app.UIFigure, 'text');
            app.CCPparametersPanel = uipanel(app.UIFigure);
            app.CCPparametersPanel.Title = 'CCP parameters:';
            app.dxkmEditFieldLabel = uilabel(app.CCPparametersPanel);
            app.dxkmEditFieldLabel.Text = 'dx (km):';
            app.dxkmEditField = uieditfield(app.CCPparametersPanel, 'numeric');
            app.dxkmEditField.Value = 20;
            app.dykmEditFieldLabel = uilabel(app.CCPparametersPanel);
            app.dykmEditFieldLabel.Text = 'dy (km):';
            app.dykmEditField = uieditfield(app.CCPparametersPanel, 'numeric');
            app.dykmEditField.Value = 10;
            app.zmaxkmEditFieldLabel = uilabel(app.CCPparametersPanel);
            app.zmaxkmEditFieldLabel.Text = 'zmax (km):';
            app.zmaxkmEditField = uieditfield(app.CCPparametersPanel, 'numeric');
            app.zmaxkmEditField.Value = 500;
            app.dzkmEditFieldLabel = uilabel(app.CCPparametersPanel);
            app.dzkmEditFieldLabel.Text = 'dz (km):';
            app.dzkmEditField = uieditfield(app.CCPparametersPanel, 'numeric');
            app.dzkmEditField.Value = 1;
            app.SmoothingkmEditFieldLabel = uilabel(app.CCPparametersPanel);
            app.SmoothingkmEditFieldLabel.Text = 'Smoothing (km):';
            app.SmoothingkmEditField = uieditfield(app.CCPparametersPanel, 'numeric');
            app.SmoothingkmEditField.Value = 15;
            app.QuitButton = uibutton(app.CCPparametersPanel, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.RunButton = uibutton(app.CCPparametersPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.VelocityModelDropDownLabel = uilabel(app.CCPparametersPanel);
            app.VelocityModelDropDownLabel.Text = 'Velocity Model:';
            app.VelocityModelDropDown = uidropdown(app.CCPparametersPanel);
            app.VelocityModelDropDown.Items = {'1D', '2D', '3D', 'Default'};
            app.VelocityModelDropDown.Value = 'Default';
            app.PlotparametersPanel = uipanel(app.UIFigure);
            app.PlotparametersPanel.Title = 'Plot parameters:';
            app.X1EditFieldLabel = uilabel(app.PlotparametersPanel);
            app.X1EditFieldLabel.Text = 'X1:';
            app.X1EditField = uieditfield(app.PlotparametersPanel, 'numeric');
            app.Y1EditFieldLabel = uilabel(app.PlotparametersPanel);
            app.Y1EditFieldLabel.Text = 'Y1:';
            app.Y1EditField = uieditfield(app.PlotparametersPanel, 'numeric');
            app.X2EditFieldLabel = uilabel(app.PlotparametersPanel);
            app.X2EditFieldLabel.Text = 'X2:';
            app.X2EditField = uieditfield(app.PlotparametersPanel, 'numeric');
            app.X2EditField.Value = 200;
            app.Y2EditFieldLabel = uilabel(app.PlotparametersPanel);
            app.Y2EditFieldLabel.Text = 'Y2:';
            app.Y2EditField = uieditfield(app.PlotparametersPanel, 'numeric');
            app.Y2EditField.Value = 130;
            app.SaveButton = uibutton(app.PlotparametersPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.RunButton_2 = uibutton(app.PlotparametersPanel, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = 'Run';
            app.StationMapPanel = uipanel(app.UIFigure);
            app.StationMapPanel.Title = 'Station Map Panel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CCPStackingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end